classdef halley < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HalleyscometUIFigure            matlab.ui.Figure
        VelocityVectorAUyearsTimeyearsLabel  matlab.ui.control.Label
        PositionVectorAUTimeyearsLabel  matlab.ui.control.Label
        PositionoftheCometintheSpaceLabel  matlab.ui.control.Label
        MinVelocityTextArea             matlab.ui.control.TextArea
        MinVelocityTextAreaLabel        matlab.ui.control.Label
        MaxVelocityTextArea             matlab.ui.control.TextArea
        MaxVelocityTextAreaLabel        matlab.ui.control.Label
        CloseButton_2                   matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        DAnimationButton                matlab.ui.control.Button
        AphelionTextArea                matlab.ui.control.TextArea
        AphelionTextAreaLabel           matlab.ui.control.Label
        PerihelionTextArea              matlab.ui.control.TextArea
        PerihelionTextAreaLabel         matlab.ui.control.Label
        PeriodTextArea                  matlab.ui.control.TextArea
        PeriodTextAreaLabel             matlab.ui.control.Label
        PlotButton_2                    matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        HalleysCometLabel               matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            iv=[0.325514 -9.096111 -0.459460 -6.916686 0.166229 -1.305721];
            [Y,t]=wsysRK4V(@F,iv,0,160,10000);
            r=sqrt(Y(:,1).*Y(:,1)+Y(:,3).*Y(:,3)+Y(:,5).*Y(:,5));
            v=sqrt(Y(:,2).*Y(:,2)+Y(:,4).*Y(:,4)+Y(:,6).*Y(:,6));
            curve1=animatedline('LineWidth',1,'parent',app.UIAxes);
            set(app.UIAxes,'XLim',[0 160],'YLim',[0 40]);
            curve2=animatedline('LineWidth',1,'parent',app.UIAxes_2);
            set(app.UIAxes_2,'XLim',[0 160],'YLim',[0 15]);
            for i=1:length(r)
                addpoints(curve1,t(i),r(i));
                addpoints(curve2,t(i),v(i));
                drawnow limitrate
            end
        end

        % Callback function
        function PlotButton_3Pushed(app, event)
   
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            iv=[0.325514 -9.096111 -0.459460 -6.916686 0.166229 -1.305721];
            [Y,t]=wsysRK4V(@F,iv,0,200,10000);
            z = Y(:,5);
            y = Y(:,3);
            x = Y(:,1);
%             plot3(Y(:,1),Y(:,3),Y(:,5),'parent',app.UIAxes_3,LineWidth=1.5,Color=[0 0 0]);
            comet3(x,y,z,'parent',app.UIAxes_3)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            iv=[0.325514 -9.096111 -0.459460 -6.916686 0.166229 -1.305721];
            [Y,t,maxr,minr,maxv,minv]=MaxMin(@F,iv,0,200,10000);
            app.AphelionTextArea.Value=num2str(maxr);
            app.PerihelionTextArea.Value=num2str(minr);
            app.MaxVelocityTextArea.Value=num2str(maxv);
            app.MinVelocityTextArea.Value=num2str(minv);
            app.PeriodTextArea.Value='76 years';
        end

        % Button pushed function: DAnimationButton
        function DAnimationButtonPushed(app, event)
            iv=[0.325514 -9.096111 -0.459460 -6.916686 0.166229 -1.305721];
            [Y,t]=wsysRK4V(@F,iv,0,200,10000);
            myworld = vrworld('VRML1.WRL');
            open(myworld);
            set(myworld, 'Description', "Halley's Comet 3D Animation");
            view(myworld);
            % nodes(myworld);
            % mynodes = get(myworld, 'Nodes');
            % fields(myworld.Comet);
            for i=1:length(t)
            myworld.Comet.translation = [Y(i,1)/5  Y(i,3)/5 Y(i,5)/5];
%             i
            pause(0.0005)
            end
        end

        % Button pushed function: CloseButton_2
        function CloseButton_2Pushed(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HalleyscometUIFigure = uifigure('Visible', 'off');
            app.HalleyscometUIFigure.Name = 'Halley''s comet';
            app.HalleyscometUIFigure.Icon = fullfile(pathToMLAPP, 'orbit-512.ico');
            app.UIAxes = uiaxes(app.HalleyscometUIFigure);
            title(app.UIAxes, 'Position Vector (AU) / Time (years)')
            app.UIAxes.XLim = [0 160];
            app.UIAxes.YLim = [0 40];
            app.UIAxes_2 = uiaxes(app.HalleyscometUIFigure);
            title(app.UIAxes_2, 'Velocity Vector (AU/years) / Time (years)')
            app.UIAxes_2.XLim = [0 160];
            app.UIAxes_2.YLim = [0 15];
            app.UIAxes_3 = uiaxes(app.HalleyscometUIFigure);
            title(app.UIAxes_3, 'Position of the Comet in the Space')
            app.Label = uilabel(app.HalleyscometUIFigure);
            app.Label.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.Label_2 = uilabel(app.HalleyscometUIFigure);
            app.Label_2.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.Label_3 = uilabel(app.HalleyscometUIFigure);
            app.Label_3.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.Label_4 = uilabel(app.HalleyscometUIFigure);
            app.Label_4.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.Label_5 = uilabel(app.HalleyscometUIFigure);
            app.Label_5.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.Label_6 = uilabel(app.HalleyscometUIFigure);
            app.Label_6.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.Label_7 = uilabel(app.HalleyscometUIFigure);
            app.Label_7.Text = {'· . ·'; ''; '✦  ˚ 　· 　　    .'; ''; '                                            · •. '; '* .  •   　 ·              · • '; ''; '               ✶   · .   '; '· .   ✶                                             . ✶'; ''; '                   · .　　  ·'; ''; '           .                     　　 　　 * '; '     ✶.                      · •.    '; ''; '                    * ˚  　　　 　 · . ·              '; '  · .         '; '         ˚ 　                         ˚ 　  ✶'; ''; ' ✶ · .'};
            app.HalleysCometLabel = uilabel(app.HalleyscometUIFigure);
            app.HalleysCometLabel.Text = 'Halley''s Comet';
            app.PlotButton = uibutton(app.HalleyscometUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.HalleyscometUIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.PeriodTextAreaLabel = uilabel(app.HalleyscometUIFigure);
            app.PeriodTextAreaLabel.Text = 'Period';
            app.PeriodTextArea = uitextarea(app.HalleyscometUIFigure);
            app.PeriodTextArea.Editable = 'off';
            app.PerihelionTextAreaLabel = uilabel(app.HalleyscometUIFigure);
            app.PerihelionTextAreaLabel.Text = 'Perihelion';
            app.PerihelionTextArea = uitextarea(app.HalleyscometUIFigure);
            app.PerihelionTextArea.Editable = 'off';
            app.AphelionTextAreaLabel = uilabel(app.HalleyscometUIFigure);
            app.AphelionTextAreaLabel.Text = 'Aphelion';
            app.AphelionTextArea = uitextarea(app.HalleyscometUIFigure);
            app.AphelionTextArea.Editable = 'off';
            app.DAnimationButton = uibutton(app.HalleyscometUIFigure, 'push');
            app.DAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @DAnimationButtonPushed, true);
            app.DAnimationButton.Text = '3D Animation';
            app.CalculateButton = uibutton(app.HalleyscometUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CloseButton_2 = uibutton(app.HalleyscometUIFigure, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_2Pushed, true);
            app.CloseButton_2.Text = 'Close';
            app.MaxVelocityTextAreaLabel = uilabel(app.HalleyscometUIFigure);
            app.MaxVelocityTextAreaLabel.Text = 'Max Velocity';
            app.MaxVelocityTextArea = uitextarea(app.HalleyscometUIFigure);
            app.MaxVelocityTextArea.Editable = 'off';
            app.MinVelocityTextAreaLabel = uilabel(app.HalleyscometUIFigure);
            app.MinVelocityTextAreaLabel.Text = 'Min Velocity';
            app.MinVelocityTextArea = uitextarea(app.HalleyscometUIFigure);
            app.MinVelocityTextArea.Editable = 'off';
            app.PositionoftheCometintheSpaceLabel = uilabel(app.HalleyscometUIFigure);
            app.PositionoftheCometintheSpaceLabel.Text = 'Position of the Comet in the Space';
            app.PositionVectorAUTimeyearsLabel = uilabel(app.HalleyscometUIFigure);
            app.PositionVectorAUTimeyearsLabel.Text = 'Position Vector (AU) / Time (years)';
            app.VelocityVectorAUyearsTimeyearsLabel = uilabel(app.HalleyscometUIFigure);
            app.VelocityVectorAUyearsTimeyearsLabel.Text = 'Velocity Vector (AU/years) / Time (years)';
            % Show the figure after all components are created
            app.HalleyscometUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = halley

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HalleyscometUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HalleyscometUIFigure)
        end
    end
end